classdef GUI_TLE2LLA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetupLLAsearchR2BPUIFigure      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        AutoCheckBox                    matlab.ui.control.CheckBox
        ECEFCheckBox                    matlab.ui.control.CheckBox
        ResetFolderButton               matlab.ui.control.Button
        offSatsTextArea                 matlab.ui.control.TextArea
        offSatsTextAreaLabel            matlab.ui.control.Label
        UnlockbuttonsButton             matlab.ui.control.Button
        NofSatsTextArea                 matlab.ui.control.TextArea
        NofSatsTextAreaLabel            matlab.ui.control.Label
        PlottedsatnameTextArea          matlab.ui.control.TextArea
        PlottedsatnameTextAreaLabel     matlab.ui.control.Label
        MaxarraysizeBytesEditField      matlab.ui.control.EditField
        MaxarraysizeBytesEditField_2Label  matlab.ui.control.Label
        PeriodEditField                 matlab.ui.control.EditField
        PeriodEditFieldLabel            matlab.ui.control.Label
        PeriodiccyclemodeCheckBox       matlab.ui.control.CheckBox
        ContinueButton                  matlab.ui.control.Button
        AutoContinueCheckBox            matlab.ui.control.CheckBox
        SampleTimesecondsEditField      matlab.ui.control.NumericEditField
        SampleTimesecondsLabel          matlab.ui.control.Label
        SimulationTimedurationEditField  matlab.ui.control.EditField
        SimulationTimedurationEditFieldLabel  matlab.ui.control.Label
        WalkerConstellationPanel        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        degEditField                    matlab.ui.control.EditField
        degEditField_2Label             matlab.ui.control.Label
        eEditField                      matlab.ui.control.EditField
        eEditFieldLabel                 matlab.ui.control.Label
        FspacingEditField               matlab.ui.control.EditField
        FspacingEditField_2Label        matlab.ui.control.Label
        TnofsatsEditField               matlab.ui.control.EditField
        TnofsatsEditField_2Label        matlab.ui.control.Label
        PplanesEditField                matlab.ui.control.EditField
        PplanesEditField_2Label         matlab.ui.control.Label
        idegEditField                   matlab.ui.control.EditField
        akmEditField_2Label_2           matlab.ui.control.Label
        akmEditField                    matlab.ui.control.EditField
        akmEditFieldLabel               matlab.ui.control.Label
        EpochEditField                  matlab.ui.control.EditField
        EpochEditFieldLabel             matlab.ui.control.Label
        SwitchtoWalkerorbitCheckBox     matlab.ui.control.CheckBox
        DirectoryPathEditField          matlab.ui.control.EditField
        DirectoryPathEditFieldLabel     matlab.ui.control.Label
        SatellitetoplotninputnEditField  matlab.ui.control.EditField
        LLAfilenameincludematLabel_2    matlab.ui.control.Label
        LLAvariablenameEditField        matlab.ui.control.EditField
        LLAfilenameincludematLabel      matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        ExectimeTextArea                matlab.ui.control.TextArea
        ExectimeTextAreaLabel           matlab.ui.control.Label
        SatellitesNumberTextArea        matlab.ui.control.TextArea
        SatellitesNumberLabel           matlab.ui.control.Label
        GroundStationPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        MinimumElevationradEditField    matlab.ui.control.EditField
        MinimumElevationradEditField_2Label  matlab.ui.control.Label
        AltitudegeodetickmEditField     matlab.ui.control.EditField
        AltitudegeodetickmEditField_3Label  matlab.ui.control.Label
        LongituderadEditField           matlab.ui.control.EditField
        LongituderadEditField_4Label    matlab.ui.control.Label
        LatitudegeodeticradEditField    matlab.ui.control.EditField
        LatitudegeodeticradEditField_3Label  matlab.ui.control.Label
        GroundStationNameEditField      matlab.ui.control.EditField
        GroundStationNameLabel          matlab.ui.control.Label
        ConstellationsfromCelesTrakorgPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        htrkmEditField                  matlab.ui.control.EditField
        htrkmLabel                      matlab.ui.control.Label
        SatelliteSearchEditField        matlab.ui.control.EditField
        SatelliteNameLabel              matlab.ui.control.Label
        NumberofsatellitesperpageEditField  matlab.ui.control.EditField
        NumberofsatellitesperpageLabel  matlab.ui.control.Label
        PageEditField                   matlab.ui.control.NumericEditField
        PageLabel                       matlab.ui.control.Label
        ConstellationNameEditField      matlab.ui.control.EditField
        ConstellationNameLabel          matlab.ui.control.Label
        QuerySatellitesCheckBox         matlab.ui.control.CheckBox
        updateTLEonlineCheckBox         matlab.ui.control.CheckBox
        StartRoutineButton              matlab.ui.control.Button
        PlotsTab                        matlab.ui.container.Tab
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    properties (Access = public)
        LLA % LLA CYCLE
        ECI
        DATA
        DATA_SAT
        T
        folderpath
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uiwait(app.SetupLLAsearchR2BPUIFigure)
        end

        % Button pushed function: StartRoutineButton
        function StartRoutineButtonPushed(app, event)
            app.UIAxes1.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.StartRoutineButton.Enable = false;
            app.ContinueButton.Enable = false;
            app.PlotButton.Enable = false;
            run('TLEsearch_tool.m')
            if app.AutoContinueCheckBox.Value == true
                uiresume(app.SetupLLAsearchR2BPUIFigure)
            else
                app.StartRoutineButton.Enable = true;
                app.ContinueButton.Enable = true;
                app.PlotButton.Enable = true;
            end
        end

        % Value changed function: QuerySatellitesCheckBox
        function QuerySatellitesCheckBoxValueChanged(app, event)
            value = app.QuerySatellitesCheckBox.Value;
            if value == 0
                app.SatelliteSearchEditField.Enable = 'off';
                app.SatellitesNumberTextArea.Enable = 'off';
                app.NumberofsatellitesperpageEditField.Enable = 'on';
            elseif value == 1
                app.SatelliteSearchEditField.Enable = 'on';
                app.SatellitesNumberTextArea.Enable = 'on';
                app.NumberofsatellitesperpageEditField.Value = 'all';
                app.NumberofsatellitesperpageEditField.Enable = 'off';
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            run('plots_LLA.m')
        end

        % Value changed function: SwitchtoWalkerorbitCheckBox
        function SwitchtoWalkerorbitCheckBoxValueChanged(app, event)
            value = app.SwitchtoWalkerorbitCheckBox.Value;
            if value == true
                app.ConstellationsfromCelesTrakorgPanel.Enable = false;
                app.WalkerConstellationPanel.Enable = true;
                app.SatellitesNumberTextArea.Enable = 'off';
                app.NofSatsTextArea.Enable = false;
                app.PlottedsatnameTextArea.Enable = false;
            else
                app.ConstellationsfromCelesTrakorgPanel.Enable = true;
                app.WalkerConstellationPanel.Enable = false;
                app.NofSatsTextArea.Enable = true;
                app.PlottedsatnameTextArea.Enable = true;
                if app.QuerySatellitesCheckBox.Value == true
                    app.SatellitesNumberTextArea.Enable = 'on';
                end
            end
        end

        % Value changed function: PeriodiccyclemodeCheckBox
        function PeriodiccyclemodeCheckBoxValueChanged(app, event)
            value = app.PeriodiccyclemodeCheckBox.Value;
            if value == true
                app.MaxarraysizeBytesEditField.Enable = false;
                app.AutoCheckBox.Enable = true;
                app.PeriodEditField.Enable = true;
                if app.AutoCheckBox.Value == true
                    app.PeriodEditField.Enable = false;
                    app.MaxarraysizeBytesEditField.Enable = true;
                end
            else
                app.PeriodEditField.Enable = false;
                app.MaxarraysizeBytesEditField.Enable = false;
                app.AutoCheckBox.Enable = false;
            end
        end

        % Value changed function: AutoContinueCheckBox
        function AutoContinueCheckBoxValueChanged(app, event)
            value = app.AutoContinueCheckBox.Value;
            if value == true || app.StartRoutineButton.Enable == false
                app.ContinueButton.Enable = false;
                app.PlotButton.Enable = false;
            else 
                app.ContinueButton.Enable = true;
                app.PlotButton.Enable = true;
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.ContinueButton.Enable = false;
            uiresume(app.SetupLLAsearchR2BPUIFigure)
        end

        % Button pushed function: UnlockbuttonsButton
        function UnlockbuttonsButtonPushed(app, event)
            app.ContinueButton.Enable = true;
            app.PlotButton.Enable = true;
            app.StartRoutineButton.Enable = true;
        end

        % Button pushed function: ResetFolderButton
        function ResetFolderButtonPushed(app, event)
            cd(app.folderpath)
        end

        % Value changed function: ECEFCheckBox
        function ECEFCheckBoxValueChanged(app, event)
            value = app.ECEFCheckBox.Value;
            if value == 1   
                app.LLAvariablenameEditField.Enable = false;
            else
                app.LLAvariablenameEditField.Enable = true;
            end
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            value = app.AutoCheckBox.Value;
            if value == true
                app.PeriodEditField.Enable = false;
                app.MaxarraysizeBytesEditField.Enable = true;
            else
                app.PeriodEditField.Enable = true;
                app.MaxarraysizeBytesEditField.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SetupLLAsearchR2BPUIFigure = uifigure('Visible', 'off');
            app.SetupLLAsearchR2BPUIFigure.Name = 'Setup LLA search (R2BP)';
            app.SetupLLAsearchR2BPUIFigure.Icon = fullfile(pathToMLAPP, 'icon1.png');
            app.TabGroup = uitabgroup(app.SetupLLAsearchR2BPUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.GridLayout = uigridlayout(app.MainTab);
            app.StartRoutineButton = uibutton(app.GridLayout, 'push');
            app.StartRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @StartRoutineButtonPushed, true);
            app.StartRoutineButton.Tag = 'RUN';
            app.StartRoutineButton.Tooltip = {'Run LLA  generation'};
            app.StartRoutineButton.Text = 'Start Routine';
            app.ConstellationsfromCelesTrakorgPanel = uipanel(app.GridLayout);
            app.ConstellationsfromCelesTrakorgPanel.Title = 'Constellations from CelesTrak.org';
            app.GridLayout2 = uigridlayout(app.ConstellationsfromCelesTrakorgPanel);
            app.updateTLEonlineCheckBox = uicheckbox(app.GridLayout2);
            app.updateTLEonlineCheckBox.Tooltip = {'Remember not to abuse it, Celestrak.org may ban the user for 2 hours'};
            app.updateTLEonlineCheckBox.Text = 'update TLE online';
            app.QuerySatellitesCheckBox = uicheckbox(app.GridLayout2);
            app.QuerySatellitesCheckBox.ValueChangedFcn = createCallbackFcn(app, @QuerySatellitesCheckBoxValueChanged, true);
            app.QuerySatellitesCheckBox.Tooltip = {'Search from original CelesTrack page by "Satellite Search" keywords'};
            app.QuerySatellitesCheckBox.Text = 'Query Satellites';
            app.QuerySatellitesCheckBox.Value = true;
            app.ConstellationNameLabel = uilabel(app.GridLayout2);
            app.ConstellationNameLabel.Text = 'Constellation Name';
            app.ConstellationNameEditField = uieditfield(app.GridLayout2, 'text');
            app.ConstellationNameEditField.Tag = 'Const_Name';
            app.ConstellationNameEditField.Value = 'ses';
            app.PageLabel = uilabel(app.GridLayout2);
            app.PageLabel.Visible = 'off';
            app.PageLabel.Text = 'Page';
            app.PageEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PageEditField.Visible = 'off';
            app.PageEditField.Value = 1;
            app.NumberofsatellitesperpageLabel = uilabel(app.GridLayout2);
            app.NumberofsatellitesperpageLabel.Tooltip = {'Set ''all'' to evaluate the full page; set a number to evaluate the first n satellites'};
            app.NumberofsatellitesperpageLabel.Text = 'Number of satellites per page';
            app.NumberofsatellitesperpageEditField = uieditfield(app.GridLayout2, 'text');
            app.NumberofsatellitesperpageEditField.InputType = 'alphanumerics';
            app.NumberofsatellitesperpageEditField.Enable = 'off';
            app.NumberofsatellitesperpageEditField.Tooltip = {'Set ''all'' to evaluate the full page; set a number to evaluate the first n satellites'};
            app.NumberofsatellitesperpageEditField.Value = 'all';
            app.SatelliteNameLabel = uilabel(app.GridLayout2);
            app.SatelliteNameLabel.Tooltip = {'Search by keywords'};
            app.SatelliteNameLabel.Text = 'Satellite Search';
            app.SatelliteSearchEditField = uieditfield(app.GridLayout2, 'text');
            app.SatelliteSearchEditField.Tooltip = {'Search by keywords'};
            app.SatelliteSearchEditField.Value = 'o3b';
            app.htrkmLabel = uilabel(app.GridLayout2);
            app.htrkmLabel.Tooltip = {'Set minimum altitude threshold: below this value, the satellite is considered off nominal orbit'};
            app.htrkmLabel.Text = 'htr [km]';
            app.htrkmEditField = uieditfield(app.GridLayout2, 'text');
            app.htrkmEditField.Tooltip = {'Set minimum altitude threshold: below this value, the satellite is considered off nominal orbit'};
            app.htrkmEditField.Value = '160';
            app.GroundStationPanel = uipanel(app.GridLayout);
            app.GroundStationPanel.Title = 'Ground Station';
            app.GridLayout3 = uigridlayout(app.GroundStationPanel);
            app.GroundStationNameLabel = uilabel(app.GridLayout3);
            app.GroundStationNameLabel.Text = ' Ground Station Name';
            app.GroundStationNameEditField = uieditfield(app.GridLayout3, 'text');
            app.GroundStationNameEditField.Value = 'Spino d''Adda';
            app.LatitudegeodeticradEditField_3Label = uilabel(app.GridLayout3);
            app.LatitudegeodeticradEditField_3Label.Text = ' Latitude (geodetic [rad])';
            app.LatitudegeodeticradEditField = uieditfield(app.GridLayout3, 'text');
            app.LatitudegeodeticradEditField.Value = '45.4*pi/180';
            app.LongituderadEditField_4Label = uilabel(app.GridLayout3);
            app.LongituderadEditField_4Label.Text = ' Longitude [rad]';
            app.LongituderadEditField = uieditfield(app.GridLayout3, 'text');
            app.LongituderadEditField.Value = '9.5*pi/180';
            app.AltitudegeodetickmEditField_3Label = uilabel(app.GridLayout3);
            app.AltitudegeodetickmEditField_3Label.Text = ' Altitude (geodetic [km])';
            app.AltitudegeodetickmEditField = uieditfield(app.GridLayout3, 'text');
            app.AltitudegeodetickmEditField.Value = '84*1e-3';
            app.MinimumElevationradEditField_2Label = uilabel(app.GridLayout3);
            app.MinimumElevationradEditField_2Label.Text = ' Minimum Elevation [rad]';
            app.MinimumElevationradEditField = uieditfield(app.GridLayout3, 'text');
            app.MinimumElevationradEditField.Value = '5*pi/180';
            app.SatellitesNumberLabel = uilabel(app.GridLayout);
            app.SatellitesNumberLabel.Tooltip = {'Queried active satellite(s)'' number(s) from the original webpage. Read Only'};
            app.SatellitesNumberLabel.Text = 'Satellite(s)'' Number';
            app.SatellitesNumberTextArea = uitextarea(app.GridLayout);
            app.SatellitesNumberTextArea.Editable = 'off';
            app.SatellitesNumberTextArea.Tooltip = {'Queried active satellite(s)'' number(s) from the original webpage. Read Only'};
            app.ExectimeTextAreaLabel = uilabel(app.GridLayout);
            app.ExectimeTextAreaLabel.Tooltip = {'Total time for LLA generation. It is updated as soon as the procedure is completed. Read Only'};
            app.ExectimeTextAreaLabel.Text = 'Exec. time';
            app.ExectimeTextArea = uitextarea(app.GridLayout);
            app.ExectimeTextArea.Tag = 'CMDWIN';
            app.ExectimeTextArea.Editable = 'off';
            app.ExectimeTextArea.Tooltip = {'Total time for LLA generation. It is updated as soon as the procedure is completed. Read Only'};
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tag = 'RUN';
            app.PlotButton.Tooltip = {'Run Plot of a specified satellite'};
            app.PlotButton.Text = 'Plot';
            app.LLAfilenameincludematLabel = uilabel(app.GridLayout);
            app.LLAfilenameincludematLabel.Text = 'LLA variable name';
            app.LLAvariablenameEditField = uieditfield(app.GridLayout, 'text');
            app.LLAvariablenameEditField.Value = 'LLA';
            app.LLAfilenameincludematLabel_2 = uilabel(app.GridLayout);
            app.LLAfilenameincludematLabel_2.Tooltip = {'Sat number: 1 to [N. of Sats] if "Constellations from Celestrak.org"; 1 to [T*P] if "Walker Constellation"'; 'Input number: set it for "Walker Constellation" only.'; 'If a higher number is set, the last satellite is chosen'};
            app.LLAfilenameincludematLabel_2.Text = 'Satellite to plot (n, input n)';
            app.SatellitetoplotninputnEditField = uieditfield(app.GridLayout, 'text');
            app.SatellitetoplotninputnEditField.Tooltip = {'Sat number: 1 to [N. of Sats] if "Constellations from Celestrak.org"; 1 to [T*P] if "Walker Constellation"'; 'Input number: set it for "Walker Constellation" only.'; 'If a higher number is set, the last satellite is chosen'};
            app.SatellitetoplotninputnEditField.Value = '[1,1]';
            app.DirectoryPathEditFieldLabel = uilabel(app.GridLayout);
            app.DirectoryPathEditFieldLabel.Enable = 'off';
            app.DirectoryPathEditFieldLabel.Visible = 'off';
            app.DirectoryPathEditFieldLabel.Text = 'Directory Path';
            app.DirectoryPathEditField = uieditfield(app.GridLayout, 'text');
            app.DirectoryPathEditField.Editable = 'off';
            app.DirectoryPathEditField.Enable = 'off';
            app.DirectoryPathEditField.Visible = 'off';
            app.DirectoryPathEditField.Value = 'D:\Courses\Thesis\TLEsearch';
            app.SwitchtoWalkerorbitCheckBox = uicheckbox(app.GridLayout);
            app.SwitchtoWalkerorbitCheckBox.ValueChangedFcn = createCallbackFcn(app, @SwitchtoWalkerorbitCheckBoxValueChanged, true);
            app.SwitchtoWalkerorbitCheckBox.Text = 'Switch to Walker orbit';
            app.WalkerConstellationPanel = uipanel(app.GridLayout);
            app.WalkerConstellationPanel.Tooltip = {'All fields (but "Epoch") can be filled with multiple input numbers, spaced by a comma, in order to build multiple Walker constellations'};
            app.WalkerConstellationPanel.Enable = 'off';
            app.WalkerConstellationPanel.Title = 'Walker Constellation';
            app.GridLayout4 = uigridlayout(app.WalkerConstellationPanel);
            app.GridLayout4.Tooltip = {''};
            app.EpochEditFieldLabel = uilabel(app.GridLayout4);
            app.EpochEditFieldLabel.Tooltip = {'[YYYY,MM,DD,hh,mm,ss]'};
            app.EpochEditFieldLabel.Text = 'Epoch';
            app.EpochEditField = uieditfield(app.GridLayout4, 'text');
            app.EpochEditField.Tooltip = {'[YYYY,MM,DD,hh,mm,ss]'};
            app.EpochEditField.Value = '2024,4,16,0,0,0';
            app.akmEditFieldLabel = uilabel(app.GridLayout4);
            app.akmEditFieldLabel.Tooltip = {'Semi-major axis'};
            app.akmEditFieldLabel.Text = 'a [km] ';
            app.akmEditField = uieditfield(app.GridLayout4, 'text');
            app.akmEditField.Tooltip = {'Semi-major axis'};
            app.akmEditField.Value = '6371+500';
            app.akmEditField_2Label_2 = uilabel(app.GridLayout4);
            app.akmEditField_2Label_2.Tooltip = {'Inclination'};
            app.akmEditField_2Label_2.Text = 'i [deg]';
            app.idegEditField = uieditfield(app.GridLayout4, 'text');
            app.idegEditField.Tooltip = {'Inclination'};
            app.idegEditField.Value = '56';
            app.PplanesEditField_2Label = uilabel(app.GridLayout4);
            app.PplanesEditField_2Label.Tooltip = {'Number of equally spaced geometric planes '};
            app.PplanesEditField_2Label.Text = 'P (planes)';
            app.PplanesEditField = uieditfield(app.GridLayout4, 'text');
            app.PplanesEditField.Tooltip = {'Number of equally spaced geometric planes '};
            app.PplanesEditField.Value = '3';
            app.TnofsatsEditField_2Label = uilabel(app.GridLayout4);
            app.TnofsatsEditField_2Label.Tooltip = {'Number of satellites per plane'};
            app.TnofsatsEditField_2Label.Text = 'T (n. of sats)';
            app.TnofsatsEditField = uieditfield(app.GridLayout4, 'text');
            app.TnofsatsEditField.Tooltip = {'Number of satellites per plane'};
            app.TnofsatsEditField.Value = '24';
            app.FspacingEditField_2Label = uilabel(app.GridLayout4);
            app.FspacingEditField_2Label.Tooltip = {'Spacing between satellites in adjacent planes'};
            app.FspacingEditField_2Label.Text = 'F (spacing)';
            app.FspacingEditField = uieditfield(app.GridLayout4, 'text');
            app.FspacingEditField.Tooltip = {'Spacing between satellites in adjacent planes'};
            app.FspacingEditField.Value = '1';
            app.eEditFieldLabel = uilabel(app.GridLayout4);
            app.eEditFieldLabel.Tooltip = {'Eccentricity'};
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.GridLayout4, 'text');
            app.eEditField.Tooltip = {'Eccentricity'};
            app.eEditField.Value = '0';
            app.degEditField_2Label = uilabel(app.GridLayout4);
            app.degEditField_2Label.Tooltip = {'Argument of Perigee'};
            app.degEditField_2Label.Text = 'ω [deg]';
            app.degEditField = uieditfield(app.GridLayout4, 'text');
            app.degEditField.Tooltip = {'Argument of Perigee'};
            app.degEditField.Value = '0';
            app.SimulationTimedurationEditFieldLabel = uilabel(app.GridLayout);
            app.SimulationTimedurationEditFieldLabel.Text = 'Simulation Time (duration)';
            app.SimulationTimedurationEditField = uieditfield(app.GridLayout, 'text');
            app.SimulationTimedurationEditField.Value = 'years(1)';
            app.SampleTimesecondsLabel = uilabel(app.GridLayout);
            app.SampleTimesecondsLabel.Text = 'Sample Time (seconds)';
            app.SampleTimesecondsEditField = uieditfield(app.GridLayout, 'numeric');
            app.SampleTimesecondsEditField.Value = 30;
            app.AutoContinueCheckBox = uicheckbox(app.GridLayout);
            app.AutoContinueCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoContinueCheckBoxValueChanged, true);
            app.AutoContinueCheckBox.Text = 'Auto Continue';
            app.AutoContinueCheckBox.Value = true;
            app.ContinueButton = uibutton(app.GridLayout, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Tag = 'RUN';
            app.ContinueButton.Tooltip = {'Resume main script execution'};
            app.ContinueButton.Text = 'Continue';
            app.PeriodiccyclemodeCheckBox = uicheckbox(app.GridLayout);
            app.PeriodiccyclemodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeriodiccyclemodeCheckBoxValueChanged, true);
            app.PeriodiccyclemodeCheckBox.Tooltip = {'This feature can accelerate the propagation, at the expense of accuracy.'; 'Please activate if thousands of satellites are evaluated w/o setting Max array size.'; 'Enable: output is single array, only Period is evaluated'; 'Disable: output is cell array. For Walker constellation, each cell contains the evaluation at each input'};
            app.PeriodiccyclemodeCheckBox.Text = 'Periodic cycle mode';
            app.PeriodiccyclemodeCheckBox.Value = true;
            app.PeriodEditFieldLabel = uilabel(app.GridLayout);
            app.PeriodEditFieldLabel.Tooltip = {'The higher, the more accurate.'};
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.GridLayout, 'text');
            app.PeriodEditField.Tooltip = {'The higher, the more accurate.'};
            app.PeriodEditField.Value = 'days(10)';
            app.MaxarraysizeBytesEditField_2Label = uilabel(app.GridLayout);
            app.MaxarraysizeBytesEditField_2Label.Tooltip = {'For large constellations (e.g. starlink) 3 GB is recommended on a 16 GB machine.'; '"max" removes any limit, please use it with caution, with small constellations. Activate Auto mode to edit this field.'};
            app.MaxarraysizeBytesEditField_2Label.Text = 'Max array size (Bytes)';
            app.MaxarraysizeBytesEditField = uieditfield(app.GridLayout, 'text');
            app.MaxarraysizeBytesEditField.Enable = 'off';
            app.MaxarraysizeBytesEditField.Tooltip = {'For large constellations (e.g. starlink) 3 GB is recommended on a 16 GB machine.'; '"max" removes any limit, please use it with caution, with small constellations. Activate Auto mode to edit this field.'};
            app.MaxarraysizeBytesEditField.Value = 'max';
            app.PlottedsatnameTextAreaLabel = uilabel(app.GridLayout);
            app.PlottedsatnameTextAreaLabel.Tooltip = {'Read Only'};
            app.PlottedsatnameTextAreaLabel.Text = 'Plotted sat name';
            app.PlottedsatnameTextArea = uitextarea(app.GridLayout);
            app.PlottedsatnameTextArea.Editable = 'off';
            app.PlottedsatnameTextArea.Tooltip = {'Read Only'};
            app.NofSatsTextAreaLabel = uilabel(app.GridLayout);
            app.NofSatsTextAreaLabel.Tooltip = {'Total number of active Satellites in the chosen webpage, or queried subpage. Read Only'};
            app.NofSatsTextAreaLabel.Text = 'N. of Sats';
            app.NofSatsTextArea = uitextarea(app.GridLayout);
            app.NofSatsTextArea.Tag = 'CMDWIN';
            app.NofSatsTextArea.Editable = 'off';
            app.NofSatsTextArea.Tooltip = {'Total number of active Satellites in the chosen webpage, or queried subpage. Read Only'};
            app.UnlockbuttonsButton = uibutton(app.GridLayout, 'push');
            app.UnlockbuttonsButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockbuttonsButtonPushed, true);
            app.UnlockbuttonsButton.Tag = 'RUN';
            app.UnlockbuttonsButton.Tooltip = {'Reset "Start Routine", "Plot", "Continue" buttons'};
            app.UnlockbuttonsButton.Text = 'Unlock buttons';
            app.offSatsTextAreaLabel = uilabel(app.GridLayout);
            app.offSatsTextAreaLabel.Tooltip = {'Number of satellites that are not operating in nominal orbit. Read Only'};
            app.offSatsTextAreaLabel.Text = 'off Sats';
            app.offSatsTextArea = uitextarea(app.GridLayout);
            app.offSatsTextArea.Tag = 'CMDWIN';
            app.offSatsTextArea.Editable = 'off';
            app.offSatsTextArea.Tooltip = {'Number of satellites that are not operating in nominal orbit. Read Only'};
            app.ResetFolderButton = uibutton(app.GridLayout, 'push');
            app.ResetFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFolderButtonPushed, true);
            app.ResetFolderButton.Tag = 'RUN';
            app.ResetFolderButton.Tooltip = {'Change directory to main folder, if directory error occurs'};
            app.ResetFolderButton.Text = 'Reset Folder';
            app.ECEFCheckBox = uicheckbox(app.GridLayout);
            app.ECEFCheckBox.ValueChangedFcn = createCallbackFcn(app, @ECEFCheckBoxValueChanged, true);
            app.ECEFCheckBox.Tooltip = {'Reference frame toggle'; 'ECI: intertial (default)'; 'ECEF: fixed'; 'Requires High Precision integration, recommened for few satellites.'; 'Disables LLA output and Ground Track plot'};
            app.ECEFCheckBox.Text = 'ECEF';
            app.AutoCheckBox = uicheckbox(app.GridLayout);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Tooltip = {'This feature chooses the period automatically, based on the rational ratio P*ωE = Q = N/D where N is the number of orbit revolutions until repeat, D is the number of days until repeat.'; 'Choose Max array size, not to run in Out of Memory error'};
            app.AutoCheckBox.Text = 'Auto';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.UIAxes2 = uiaxes(app.PlotsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes1 = uiaxes(app.PlotsTab);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.Visible = 'off';
            % Show the figure after all components are created
            app.SetupLLAsearchR2BPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_TLE2LLA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetupLLAsearchR2BPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetupLLAsearchR2BPUIFigure)
        end
    end
end